classdef NJApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FORCESTOPALLQUIZButton       matlab.ui.control.StateButton
        total_words_edit_field       matlab.ui.control.NumericEditField
        CelkemvbrslovEditFieldLabel  matlab.ui.control.Label
        words_count                  matlab.ui.control.NumericEditField
        VybranchslovnajednouEditFieldLabel  matlab.ui.control.Label
        ScorepanelPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        extra_label                  matlab.ui.control.Label
        score_label                  matlab.ui.control.Label
        ScoreLabel                   matlab.ui.control.Label
        OstatnslovajenzjednkategorieCheckBox  matlab.ui.control.CheckBox
        VechnyButton                 matlab.ui.control.Button
        categories_list_box          matlab.ui.control.ListBox
        KategorieLabel               matlab.ui.control.Label
        ControlPanel                 matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        STARTButton                  matlab.ui.control.Button
        LOADEXCELButton              matlab.ui.control.Button
        word_label                   matlab.ui.control.Label
        pick_answer_box              matlab.ui.control.ListBox
        SlovoLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        words_table
        selected_category
        only_selected_category = 1
        selected_words = 5
        quiz_length = 0

        current_table
        current_czech_word
        current_german_word

        score
        total_words
        total_words_selected
        word_is_picking
        currently_selected_word = []
        passed_the_level
        failed_round
        forced_quit = 0

        STCH
    end

    properties (Constant, Access = private)
        
    end

    properties (Access = public)
        a = 2
    end
    
    
    methods (Access = private)
        
        function loadWords(app)
            if isdeployed
                file_name = uigetfile(pwd);
            else
                file_name = dir('*.xlsx').name;
            end
            app.words_table = readtable(file_name);
        end

        function printScore(app)
            app.score_label.Text = [num2str(app.score), ' / ', num2str(app.total_words_selected)];
        end

        function addWordsToTheList(app, word_list)
            shuffled_word_list = word_list(randperm(length(word_list)));
            for i = 1:length(word_list)
                app.addWordToTheList(shuffled_word_list{i});
            end
        end
        function addWordToTheList(app, word)
            if isempty(app.pick_answer_box.Items)
                app.pick_answer_box.Items{1} = word;
            else
                app.pick_answer_box.Items{end+1} = word;
            end
        end
        function addCategoriesToTheList(app, categories)
            for i = 1:length(categories)
                app.addCategoryToTheList(categories{i})
            end
        end
        function addCategoryToTheList(app, category)
            if isempty(app.categories_list_box.Items)
                app.categories_list_box.Items{1} = category;
            else
                app.categories_list_box.Items{end+1} = category;
            end
        end

        function clearList(app)
            app.pick_answer_box.Items = {};
        end
        function clearCategories(app)
            app.categories_list_box.Items = {};
        end

        function fillCategories(app)
            app.clearCategories();
            app.addCategoriesToTheList(unique(app.words_table.('Kategorie')));
        end

        function category = getSelectedCategory(app)
            category = app.categories_list_box.Value;
        end

        function getSelectedWords(app)
            app.selected_words = app.words_count.Value;
        end

        function selectCategory(app, item_number)
            if item_number > numel(app.categories_list_box.Items)
                error('Hight item number than maximum')
            end
            app.categories_list_box.Value = app.categories_list_box.Items{item_number};
        end

        function startQuiz(app)
            app.extra_label.Text = '';
            app.quiz_length = app.total_words_edit_field.Value;
            %Category selections
            if ~isempty(app.getSelectedCategory)
                selected_rows = strcmp(app.words_table.('Kategorie'), app.getSelectedCategory);
            else
                selected_rows = 1:size(app.words_table,1);
            end
            
            app.current_table = app.words_table(selected_rows, :);
            app.total_words = size(app.current_table,1);
            if app.quiz_length == 0
                app.total_words_selected = app.total_words;
            else
                app.total_words_selected = app.quiz_length;
            end

            index_array = randperm(app.total_words_selected);
            index_array = index_array(1:app.total_words_selected);
            app.word_is_picking = 1;
            for i = index_array
                app.printScore();
                app.current_czech_word = app.current_table.('Ceske_slovo'){i};
                app.current_german_word = app.current_table.('Nemecke_slovo'){i};
                
                if app.only_selected_category
                    random_words = app.current_table.('Nemecke_slovo')(randperm(size(app.current_table,1),app.selected_words));
                else
                    random_words = app.words_table.('Nemecke_slovo')(randperm(size(app.words_table,1),app.selected_words));
                end

                app.word_label.Text = app.current_czech_word;
                app.clearList()
                app.addWordsToTheList(unique([random_words; {app.current_german_word}]))
                app.failed_round = 0;
                app.passed_the_level = 0;
                while(~app.passed_the_level&&~app.forced_quit)
                    pause(0.01)
                end
                if app.forced_quit
                    app.resetQuiz();
                    break
                end
                app.printScore();
                app.word_is_picking = 1;
            end
            app.softResetQuit();
            if (app.score == app.total_words_selected)&&(app.score~=0)
                app.extra_label.Text = 'Gratulace, plné scóre';
            end
            
        end
        function softResetQuit(app)
            app.failed_round = 0;
            app.passed_the_level = 0;
            app.extra_label.Text = '';
            app.clearList();
            app.score = 0;
            app.total_words_selected = 0;
        end
        function resetQuiz(app)
            app.failed_round = 0;
            app.passed_the_level = 0;
            app.extra_label.Text = '';
            app.clearList();
            app.score = 0;
            app.total_words_selected = 0;
            app.printScore();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Init translator
            app.selected_words = app.words_count.Value;
            
            %Load words from txts
            app.loadWords();
            app.fillCategories();
            app.selectCategory(1);
            myobj.a = 1
            app.STCH = quizFlow(my_val=app);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.score = 0;
            app.total_words_selected = 0;
            app.startQuiz()
        end

        % Value changed function: pick_answer_box
        function pick_answer_boxValueChanged(app, event)
            app.currently_selected_word = app.pick_answer_box.Value;
            if strcmp(app.currently_selected_word, app.current_german_word)
                if ~app.failed_round
                    app.score = app.score+1;
                end
                app.passed_the_level = 1;
            else
                app.failed_round = 1;
            end
        end

        % Callback function
        function VechnyCheckBoxValueChanged(app, event)

        end

        % Button pushed function: VechnyButton
        function VechnyButtonPushed(app, event)
            app.categories_list_box.Value = {};
        end

        % Button pushed function: LOADEXCELButton
        function LOADEXCELButtonPushed(app, event)
            app.loadWords()
        end

        % Value changed function: OstatnslovajenzjednkategorieCheckBox
        function OstatnslovajenzjednkategorieCheckBoxValueChanged(app, event)
            app.only_selected_category = app.OstatnslovajenzjednkategorieCheckBox.Value;
        end

        % Value changed function: words_count
        function words_countValueChanged(app, event)
            app.selected_words = app.words_count.Value;
        end

        % Value changed function: FORCESTOPALLQUIZButton
        function FORCESTOPALLQUIZButtonValueChanged(app, event)
            value = app.FORCESTOPALLQUIZButton.Value;
            if value == 1
                app.FORCESTOPALLQUIZButton.BackgroundColor = 'red';
            else
                app.FORCESTOPALLQUIZButton.BackgroundColor = '#eee';
            end
            app.forced_quit = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.STCH)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SlovoLabel = uilabel(app.UIFigure);
            app.SlovoLabel.Text = 'Slovo:';
            app.pick_answer_box = uilistbox(app.UIFigure);
            app.pick_answer_box.ValueChangedFcn = createCallbackFcn(app, @pick_answer_boxValueChanged, true);
            app.word_label = uilabel(app.UIFigure);
            app.word_label.Text = 'Vybrané slovo:';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.LOADEXCELButton = uibutton(app.GridLayout, 'push');
            app.LOADEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @LOADEXCELButtonPushed, true);
            app.LOADEXCELButton.Text = 'LOAD EXCEL';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.KategorieLabel = uilabel(app.UIFigure);
            app.KategorieLabel.Text = 'Kategorie:';
            app.categories_list_box = uilistbox(app.UIFigure);
            app.VechnyButton = uibutton(app.UIFigure, 'push');
            app.VechnyButton.ButtonPushedFcn = createCallbackFcn(app, @VechnyButtonPushed, true);
            app.VechnyButton.Text = 'Všechny';
            app.OstatnslovajenzjednkategorieCheckBox = uicheckbox(app.UIFigure);
            app.OstatnslovajenzjednkategorieCheckBox.ValueChangedFcn = createCallbackFcn(app, @OstatnslovajenzjednkategorieCheckBoxValueChanged, true);
            app.OstatnslovajenzjednkategorieCheckBox.Text = 'Ostatní slova jen z jedné kategorie';
            app.ScorepanelPanel = uipanel(app.UIFigure);
            app.ScorepanelPanel.Title = 'Score panel';
            app.GridLayout2 = uigridlayout(app.ScorepanelPanel);
            app.ScoreLabel = uilabel(app.GridLayout2);
            app.ScoreLabel.Text = 'Score:';
            app.score_label = uilabel(app.GridLayout2);
            app.score_label.Text = '0';
            app.extra_label = uilabel(app.GridLayout2);
            app.extra_label.Text = '';
            app.VybranchslovnajednouEditFieldLabel = uilabel(app.UIFigure);
            app.VybranchslovnajednouEditFieldLabel.Text = 'Vybraných slov najednou:';
            app.words_count = uieditfield(app.UIFigure, 'numeric');
            app.words_count.ValueChangedFcn = createCallbackFcn(app, @words_countValueChanged, true);
            app.words_count.Value = 5;
            app.CelkemvbrslovEditFieldLabel = uilabel(app.UIFigure);
            app.CelkemvbrslovEditFieldLabel.Text = 'Celkem výběr slov:';
            app.total_words_edit_field = uieditfield(app.UIFigure, 'numeric');
            app.FORCESTOPALLQUIZButton = uibutton(app.UIFigure, 'state');
            app.FORCESTOPALLQUIZButton.ValueChangedFcn = createCallbackFcn(app, @FORCESTOPALLQUIZButtonValueChanged, true);
            app.FORCESTOPALLQUIZButton.Text = 'FORCE STOP ALL QUIZ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NJApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end